classdef App_counter_level < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ListBoxLabel            matlab.ui.control.Label
        ListBox                 matlab.ui.control.ListBox
        ConnectButton           matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        YmaxEditFieldLabel      matlab.ui.control.Label
        YmaxEditField           matlab.ui.control.NumericEditField
        YminEditFieldLabel      matlab.ui.control.Label
        YminEditField           matlab.ui.control.NumericEditField
        TimeEditFieldLabel      matlab.ui.control.Label
        TimeEditField           matlab.ui.control.NumericEditField
        LevelEditFieldLabel     matlab.ui.control.Label
        LevelEditField          matlab.ui.control.NumericEditField
        DistanceEditFieldLabel  matlab.ui.control.Label
        DistanceEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        myTimer % Description
        myPort  % Description
        myPortOpen = 0 % Description
        DataLength = 100 % Description      
        BytesAtPort % Description
        Data % Description
        SamplesAtPort  % Description
        time_table=[0.0 0.4 7.2 14.4 24.4 24.8 30.6 36.9 46.9 47.3 52.4 58.0 68.0 73.5 79.1 89.1 89.5 94.4 99.8 109.8 115.1 120.5 130.5 135.8 141.2 151.2 151.6 156.3 161.5 171.5];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
        level_table=[5.1 0.0 0.0 0.0 9.1 0.0 0.0 0.0 11.1 0.0 0.0 0.0 11.2 0.0 0.0 12.1 0.0 0.0 0.0 12.2 0.0 0.0 12.3 0.0 0.0 13.1 0.0 0.0 0.0 13.2];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
        distance_table=[40.0 0.0 0.0 0.0 80.0 0.0 0.0 0.0 120.0 0.0 0.0 0.0 240.0 0.0 0.0 160.0 0.0 0.0 0.0 320.0 0.0 0.0 480.0 0.0 0.0 200.0 0.0 0.0 0.0 400.0];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


    end

    methods (Access = private)
    
        function update_display(app)
            
            app.BytesAtPort = get(app.myPort,'BytesAvailable');
            res = app.BytesAtPort;
            app.SamplesAtPort = floor(app.BytesAtPort/(4*1)); % (bytes * channel)
            if(app.SamplesAtPort)
                new_data = fread(app.myPort,app.SamplesAtPort*1,'int32');
                app.TimeEditField.Value=app.time_table(new_data+1);
                level_wzeros=app.level_table(new_data+1);
                if (level_wzeros>0)
                app.LevelEditField.Value=app.level_table(new_data+1);
                app.DistanceEditField.Value=app.distance_table(new_data+1);
                end
            end
        end

        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.Data = [ones(1,app.DataLength);2*ones(1,app.DataLength);3*ones(1,app.DataLength)]; %bruges dette? 
            
            S = instrhwinfo('serial');
            app.ListBox.Items = S.AvailableSerialPorts;
            app.myTimer = timer(...
                'ExecutionMode', 'fixedRate', ...       % Run timer repeatedly
                'Period', 0.1, ...                        % Initial period is 1 sec.
                'TimerFcn', @(obj,event)update_display(app));     % Specify callback function
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
                      
            if (strcmp(app.ConnectButton.Text,'Connect'))
                app.myPort = serial(app.ListBox.Value, 'TimeOut', 2, 'BaudRate', 115200, ...
                                    'InputBufferSize',8192,'ByteOrder','bigEndian');
                try
                    fopen(app.myPort)          % Dette forsøger at åbne porten
                    pause(1)
                    app.myPortOpen = 1;
                catch err
                    disp(err.message)           % Skriv en fejlmeddelelse, hvis det ikke lykkedes
                    fprintf('\nTjek enhedshåndteringen (device manager) for at finde jeres egen com-port\n\n')
                    app.myPortOpen = 0;
                end
            end
            
            if (strcmp(app.ConnectButton.Text,'Disconnect'))
                
                app.ConnectButton.Text = 'Connect'; 
                app.ListBox.Enable = 'on';
                
                app.myPortOpen = 0;
                fwrite(app.myPort,'E');
                stop(app.myTimer);
                fclose(app.myPort);
                delete(app.myPort);
            
            end
            
            if (app.myPortOpen)
                app.ConnectButton.Text = 'Disconnect';
                app.ListBox.Enable = 'off';
                
                fwrite(app.myPort,'S');
                pause(1)
                start(app.myTimer);     
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if (app.myPortOpen)
                warndlg('Close COM port before closing this window.', 'Warning Dialog', 'modal');
                return;
            end
            selection = questdlg(['Close ' app.UIFigure.Name '?'], ['Close ' app.UIFigure.Name '...'],'Yes','No','Yes');
            
            if strcmp(selection,'No')
                return;
            end
            
            %delete(app.myTimer);
            delete(app);
            
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            value = app.TimeEditField.Value;
            
        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)
            value = app.LevelEditField.Value;
      
        end

        % Value changed function: DistanceEditField
        function DistanceEditFieldValueChanged(app, event)
            value = app.DistanceEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.YmaxEditFieldLabel = uilabel(app.UIFigure);
            app.YmaxEditFieldLabel.Text = 'Ymax';
            app.YmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmaxEditField.Value = 2100;
            app.YminEditFieldLabel = uilabel(app.UIFigure);
            app.YminEditFieldLabel.Text = 'Ymin';
            app.YminEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.ValueDisplayFormat = '%.1f';
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.TimeEditField.Editable = 'off';
            app.LevelEditFieldLabel = uilabel(app.UIFigure);
            app.LevelEditFieldLabel.Text = 'Level';
            app.LevelEditField = uieditfield(app.UIFigure, 'numeric');
            app.LevelEditField.ValueDisplayFormat = '%.1f';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.LevelEditField.Editable = 'off';
            app.LevelEditField.Value = 5.1;
            app.DistanceEditFieldLabel = uilabel(app.UIFigure);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistanceEditField.ValueChangedFcn = createCallbackFcn(app, @DistanceEditFieldValueChanged, true);
            app.DistanceEditField.Editable = 'off';
            app.DistanceEditField.Value = 40;
        end
    end

    methods (Access = public)

        % Construct app
        function app = App_counter_level

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end