classdef App_for_YoYo_mlevel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        ConnectButton                   matlab.ui.control.Button
        EnhederTilgngeligeListBoxLabel  matlab.ui.control.Label
        EnhederListBox                  matlab.ui.control.ListBox
        EnhederForbundetListBoxLabel    matlab.ui.control.Label
        EnhederForbundetListBox         matlab.ui.control.ListBox
        TestTab                         matlab.ui.container.Tab
        StopButton                      matlab.ui.control.Button
        Atletnavn_textField             matlab.ui.control.EditField
        NavnLabel                       matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        AdvarslerLabel                  matlab.ui.control.Label
        LevelLabel                      matlab.ui.control.Label
        LevelEditField                  matlab.ui.control.NumericEditField
        GemdataButton                   matlab.ui.control.Button
        PulsLabel                       matlab.ui.control.Label
        Puls_textField                  matlab.ui.control.NumericEditField
        PersonLevelEditField            matlab.ui.control.NumericEditField
        LevelLabel_2                    matlab.ui.control.Label
        YoYotestIR1Label                matlab.ui.control.Label
        StopButtonPerson                matlab.ui.control.Button
        StartTestButton                 matlab.ui.control.Button
        Underscore                      matlab.ui.control.Label
        Acceleration                    matlab.ui.control.NumericEditField
        AccelerationLabel               matlab.ui.control.Label
        BPMLabel                        matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
    end


    properties (Access = private)
        myTimer % Description
        myPort  % Description
        myPortOpen = 0 % Description
        DataLength = 500 % Description      
        BytesAtPort % Description
        Data % Description
        SamplesAtPort  % Description
        %For updatedisplayv2(): 
        samples_per_channel = 1;
        num_of_channels = 4;
        data_length = 1000;        % Antal samples per kanal i plottet
        data; 
        level_table=[5.1 9.1 11.1 11.2 12.1 12.2 12.3 13.1 13.2 13.3 13.4 14.1 14.2 14.3 14.4 14.5 14.6 14.7 14.8 15.1 15.2 15.3 15.4 15.5 15.6 15.7 15.8 16.1 16.2 16.3 16.4 16.5 16.6 16.7 16.8 17.1 17.2 17.3 17.4 17.5 17.6 17.7 17.8 18.1 18.2 18.3 18.4 18.5 18.6 18.7 18.8 19.1 19.2 19.3 19.4 19.5 19.6 19.7 19.8 20.1 20.2 20.3 20.4 20.5 20.6 20.7 20.8 21.1 21.2 21.3 21.4 21.5 21.6 21.7 21.8 22.1 22.2 22.3 22.4 22.5 22.6 22.7 22.8 23.1 23.2 23.3 23.4 23.5 23.6 23.7 23.8];
        levelValue = 0;
        personName;
        testPersonEnded = 0;
        testRunning = 0;
        lampNumber = 1;
        
    end

    methods (Access = private)
    
        function update_display(app)
            
            app.BytesAtPort = get(app.myPort,'BytesAvailable');
            res = app.BytesAtPort;
            app.SamplesAtPort = floor(app.BytesAtPort/(4*3));%datatype size (int=4) and channels (3)
            if(app.SamplesAtPort)
                %new_data = fread(app.myPort,1,'int32');
                new_data = fread(app.myPort,app.SamplesAtPort*3,'int32'); %Read samples for each channel(hence *3)
                %app.OutputEditField.Value = char(new_data);
                %app.Data = [app.Data new_data(1:end)']; 
                app.Data(1,:) = [app.Data(1,1+app.SamplesAtPort:end) new_data(1:3:end)'];    %ontinuesly update data field with FIFO, where size is Data Length variable
                app.Data(2,:) = [app.Data(2,1+app.SamplesAtPort:end) new_data(2:3:end)'];    
                app.Data(3,:) = [app.Data(3,1+app.SamplesAtPort:end) new_data(3:3:end)'];
                
                 %plot(app.UIAxes,app.Data(1,:)')
                 %plot(app.UIAxes,app.Data');
                 %legend(app.UIAxes,'z','y','x');
                 %set(app.UIAxes,'YLimMode','manual','Ylim',[app.YminEditField.Value app.YmaxEditField.Value])
                 %set(app.UIAxes,'XLimMode','manual','Xlim',[0 app.DataLength])
                drawnow
            end
        end
        
        function update_displayV2(app)
            BytesAvailable = get(app.myPort,'BytesAvailable');
            data_stream = fread(app.myPort,app.samples_per_channel*app.num_of_channels,'int8');   % Laes en byte_stream fra porten
            
            for i = 1:app.num_of_channels       % Pak byte_stream data ud til en matrice
                app.data(:,i) = [app.data(app.samples_per_channel+1:end,i) ; data_stream(i:app.num_of_channels:end)];
            end  
%             for i = 1:app.num_of_channels       % Pak byte_stream data ud til en matrice
%                 app.data(:,i) = [app.data(:,i) ; data_stream(i:app.num_of_channels:end)];
%             end 
            
            %app.Puls_textField.Value = data_stream;
            %plot(app.UIAxes,app.data(:,1));
        end
    
    
        function update_display_level(app) %ændre evt til at køre denne funktion i linje 144
            
            app.BytesAtPort = get(app.myPort,'BytesAvailable');
            res = app.BytesAtPort;
            app.SamplesAtPort = floor(app.BytesAtPort/(4*1)); % (bytes * channel)
            if(app.SamplesAtPort == 1)
                new_data = fread(app.myPort,app.BytesAtPort,'uint8');
                if(sum(new_data)==40)
                    app.StartTestButton.Enable = 1;
                elseif(size(new_data,1)>3)
                    app.levelValue = app.level_table(new_data(3));
                    app.LevelEditField.Value = app.levelValue;
                    
                    switch(new_data(2))
                        case 1 %start
                            if(~new_data(4))%Event not OK
                                switch(app.lampNumber)
                                    case 1
                                        app.Lamp.Color = 'r';
                                    case 2
                                        app.Lamp_2.Color = 'r';
                                    case 3
                                        app.Lamp_3.Color = 'r';
                                    case 4
                                        app.Lamp_4.Color = 'r';
                                    otherwise
                                        %Do nothing
                                end
                                app.lampNumber = app.lampNumber + 1;
                            end
                        case 3 %stop
                            if(~new_data(4))%Event not OK
                                switch(app.lampNumber)
                                    case 1
                                        app.Lamp.Color = 'y';
                                    case 2
                                        app.Lamp_2.Color = 'y';
                                    case 3
                                        app.Lamp_3.Color = 'y';
                                    case 4
                                        app.Lamp_4.Color = 'y';
                                    otherwise
                                        %Do nothing
                                end
                                app.lampNumber = app.lampNumber + 1;
                            end
                        otherwise
                          %Do nothing  
                    end
                    
                end
            elseif (app.SamplesAtPort > 1)
                new_data = fread(app.myPort,app.BytesAtPort,'uint8');
            end
            drawnow
        end

        
        function removeFromForbundneListBox(app)
             [~,idx] = ismember(app.EnhederForbundetListBox.Value,app.EnhederForbundetListBox.Items);
            app.EnhederForbundetListBox.Items(idx) = [];
        end
        
        function addToForbundneListBox(app)
            app.EnhederForbundetListBox.Items = app.EnhederListBox.Items;
            app.EnhederForbundetListBox.Value = app.EnhederListBox.Value;
            app.Atletnavn_textField.Value = app.EnhederListBox.Value;
            app.personName = app.EnhederListBox.Value;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.Data = [ones(1,app.DataLength);2*ones(1,app.DataLength);3*ones(1,app.DataLength)];
            %app.Data = 0;
            
            
            S = instrhwinfo('serial');
            app.EnhederListBox.Items = S.AvailableSerialPorts;
            app.myTimer = timer(...
                'ExecutionMode', 'fixedRate', ...       % Run timer repeatedly
                'Period', 0.1, ...                        % Initial period is 1 sec.
                'TimerFcn', @(obj,event)update_display_level(app));     % Specify callback function
            
            %app.data = zeros(app.data_length,app.num_of_channels);   % Initialisering af data
            %app.data = 0;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
                      
            if (strcmp(app.ConnectButton.Text,'Connect'))
                app.myPort = serial(app.EnhederListBox.Value, 'TimeOut', 2, 'BaudRate', 115200, ...
                                    'InputBufferSize',8192,'ByteOrder','bigEndian');
                try
                    fopen(app.myPort)          % Dette forsøger at åbne porten
                    pause(1)
                    app.myPortOpen = 1;
                catch err
                    disp(err.message)           % Skriv en fejlmeddelelse, hvis det ikke lykkedes
                    fprintf('\nTjek enhedshåndteringen (device manager) for at finde jeres egen com-port\n\n')
                    app.myPortOpen = 0;
                end
                addToForbundneListBox(app);
            end
            
           
            if (strcmp(app.ConnectButton.Text,'Disconnect'))
                app.StartTestButton.Enable = 0;
                app.StopButtonPerson.Enable = 1;
                app.ConnectButton.Text = 'Connect'; 
                app.EnhederListBox.Enable = 'on';
                
                removeFromForbundneListBox(app);
                
                app.myPortOpen = 0;
                %fwrite(app.myPort,'E');
                stop(app.myTimer);
                fclose(app.myPort);
                delete(app.myPort);
            
            end
            
            if (app.myPortOpen)
                app.ConnectButton.Text = 'Disconnect';
                app.EnhederListBox.Enable = 'off';
                
                %fwrite(app.myPort,'S');
                pause(1)
                start(app.myTimer);     
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if (app.myPortOpen)
                warndlg('Close COM port before closing this window.', 'Warning Dialog', 'modal');
                return;
            end
            selection = questdlg(['Close ' app.UIFigure.Name '?'], ['Close ' app.UIFigure.Name '...'],'Yes','No','Yes');
            
            if strcmp(selection,'No')                
                return;
            end
            
            %delete(app.myTimer);
            delete(app);
            
        end

        % Callback function
        function SerialprintEditFieldValueChanged(app, event)
            if(app.myPortOpen)
                value = app.SerialprintEditField.Value;
                fprintf(app.myPort,'%s',value); 
            else
                warndlg('Connect COM-port','Warning','modal');
            end
            
            
        end

        % Callback function
        function EnterButtonPushed(app, event)
            SerialprintEditFieldValueChanged(app,event);
        end

        % Button pushed function: GemdataButton
        function GemdataButtonPushed(app, event)
        if(app.testPersonEnded)    
            [save_name, path_name] = uiputfile('*.mat');
            if(isstring(save_name) || ischar(save_name))
                save_name = [path_name save_name];
                name = app.personName;
                level = app.PersonLevelEditField.Value;
                save(save_name,'name', 'level');
            else
                warndlg('No Data saved!', 'Warning Dialog', 'modal');
            end
         else
            warndlg('Stop test for person!', 'Warning Dialog', 'modal');
         end
    
            
        end

        % Button pushed function: StartTestButton
        function StartTestButtonPushed(app, event)
            if(app.myPortOpen)
                fprintf(app.myPort,'%s','S');
                app.testRunning = 1;
            else
                warndlg('Connect COM-port','Warning','modal');
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if(app.myPortOpen)
                fprintf(app.myPort,'%s','E');
                app.testRunning = 0;
                app.StopButtonPerson.Enable = 1;
            else
                warndlg('Connect COM-port','Warning','modal');
            end
        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)
            value = app.LevelEditField.Value;
        end

        % Button pushed function: StopButtonPerson
        function StopButtonPersonPushed(app, event)
            if(app.testRunning)
                app.PersonLevelEditField.Value = app.levelValue;
                app.testPersonEnded = 1;
                app.StopButtonPerson.Enable = 0;
            else
                warndlg('Start test before stopping','Warning','modal');
            end
            
        end

        % Value changed function: Atletnavn_textField
        function Atletnavn_textFieldValueChanged(app, event)
            value = app.Atletnavn_textField.Value;
            app.personName = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.ConnectButton = uibutton(app.SetupTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.EnhederTilgngeligeListBoxLabel = uilabel(app.SetupTab);
            app.EnhederTilgngeligeListBoxLabel.Text = 'Enheder Tilgængelige';
            app.EnhederListBox = uilistbox(app.SetupTab);
            app.EnhederForbundetListBoxLabel = uilabel(app.SetupTab);
            app.EnhederForbundetListBoxLabel.Text = 'Enheder Forbundet';
            app.EnhederForbundetListBox = uilistbox(app.SetupTab);
            app.EnhederForbundetListBox.Items = {};
            app.EnhederForbundetListBox.Value = {};
            app.TestTab = uitab(app.TabGroup);
            app.TestTab.Title = 'Test';
            app.StopButton = uibutton(app.TestTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Atletnavn_textField = uieditfield(app.TestTab, 'text');
            app.Atletnavn_textField.ValueChangedFcn = createCallbackFcn(app, @Atletnavn_textFieldValueChanged, true);
            app.NavnLabel = uilabel(app.TestTab);
            app.NavnLabel.Text = 'Navn';
            app.Lamp = uilamp(app.TestTab);
            app.Lamp_2 = uilamp(app.TestTab);
            app.Lamp_3 = uilamp(app.TestTab);
            app.Lamp_4 = uilamp(app.TestTab);
            app.AdvarslerLabel = uilabel(app.TestTab);
            app.AdvarslerLabel.Text = 'Advarsler';
            app.LevelLabel = uilabel(app.TestTab);
            app.LevelLabel.Text = 'Level';
            app.LevelEditField = uieditfield(app.TestTab, 'numeric');
            app.LevelEditField.ValueDisplayFormat = '%.1f';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.LevelEditField.Editable = 'off';
            app.GemdataButton = uibutton(app.TestTab, 'push');
            app.GemdataButton.ButtonPushedFcn = createCallbackFcn(app, @GemdataButtonPushed, true);
            app.GemdataButton.Text = 'Gem data';
            app.PulsLabel = uilabel(app.TestTab);
            app.PulsLabel.Enable = 'off';
            app.PulsLabel.Text = 'Puls';
            app.Puls_textField = uieditfield(app.TestTab, 'numeric');
            app.Puls_textField.Editable = 'off';
            app.Puls_textField.Enable = 'off';
            app.PersonLevelEditField = uieditfield(app.TestTab, 'numeric');
            app.PersonLevelEditField.ValueDisplayFormat = '%.1f';
            app.PersonLevelEditField.Editable = 'off';
            app.LevelLabel_2 = uilabel(app.TestTab);
            app.LevelLabel_2.Text = 'Level';
            app.YoYotestIR1Label = uilabel(app.TestTab);
            app.YoYotestIR1Label.Text = 'Yo-Yo test: IR1';
            app.StopButtonPerson = uibutton(app.TestTab, 'push');
            app.StopButtonPerson.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPersonPushed, true);
            app.StopButtonPerson.Text = 'Stop';
            app.StartTestButton = uibutton(app.TestTab, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @StartTestButtonPushed, true);
            app.StartTestButton.Enable = 'off';
            app.StartTestButton.Text = 'Start Test';
            app.Underscore = uilabel(app.TestTab);
            app.Underscore.Text = '________________________________________________________________________________';
            app.Acceleration = uieditfield(app.UIFigure, 'numeric');
            app.AccelerationLabel = uilabel(app.UIFigure);
            app.AccelerationLabel.Text = 'Acceleration';
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = ' BPM';
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = ' m/s';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_for_YoYo_mlevel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end